classdef Interaction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        AmplitudeSliderLabel            matlab.ui.control.Label
        AmplitudeSlider                 matlab.ui.control.Slider
        ConcentrationMinEditFieldLabel  matlab.ui.control.Label
        ConcentrationMinEditField       matlab.ui.control.NumericEditField
        ConcentrationMaxEditFieldLabel  matlab.ui.control.Label
        ConcentrationMaxEditField       matlab.ui.control.NumericEditField
        ConcentrationAEditFieldLabel    matlab.ui.control.Label
        ConcentrationAEditField         matlab.ui.control.NumericEditField
        Switch                          matlab.ui.control.Switch
        NumberoftestpersonEditFieldLabel  matlab.ui.control.Label
        NumberoftestpersonEditField     matlab.ui.control.NumericEditField
        LampLabel                       matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        TabGroup                        matlab.ui.container.TabGroup
        CoSplitTab                      matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        NumberEditFieldLabel            matlab.ui.control.Label
        NumberEditField                 matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        ConcentrationBEditFieldLabel    matlab.ui.control.Label
        ConcentrationBEditField         matlab.ui.control.NumericEditField
        RefreshButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value*peaks)
        end

        % Callback function
        function AmplitudeSliderValueChanging(app, event)
  
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.UIAxes.XScale = value;
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            method = app.MethodDropDown.Value;
            app.Label.Text = app.MethodDropDown.Value;
            
            if method == "Maximum Pool Size"
                app.UIAxes2.XScale = 'linear';
                app.NumberEditField.Limits = [1, inf];
                plot(app.UIAxes2, app.NumberEditField.Value*peaks)
                drawnow
            elseif method == "Methode 2"
                app.NumberEditField.Limits = [-inf, inf];
               x = linspace(0,3);
                y1 = sin(5*x);
                y2 = sin(15*x);
                tiledlayout(2,1)
                drawnow

                % Top plot
            plot(app.UIAxes2,x,y1)
            
           end


        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            drawnow(app.UIAxes2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Concentration')
            ylabel(app.UIAxes, 'Probability')
            app.UIAxes.XLim = [-10000 10000];
            app.UIAxes.XScale = 'log';
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [-2 2];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.ConcentrationMinEditFieldLabel = uilabel(app.UIFigure);
            app.ConcentrationMinEditFieldLabel.Text = 'Concentration Min';
            app.ConcentrationMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConcentrationMaxEditFieldLabel = uilabel(app.UIFigure);
            app.ConcentrationMaxEditFieldLabel.Text = 'Concentration Max';
            app.ConcentrationMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConcentrationAEditFieldLabel = uilabel(app.UIFigure);
            app.ConcentrationAEditFieldLabel.Text = 'Concentration A';
            app.ConcentrationAEditField = uieditfield(app.UIFigure, 'numeric');
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'log', 'linear'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'log';
            app.NumberoftestpersonEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoftestpersonEditFieldLabel.Text = 'Number of test person';
            app.NumberoftestpersonEditField = uieditfield(app.UIFigure, 'numeric');
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CoSplitTab = uitab(app.TabGroup);
            app.CoSplitTab.Title = 'CoSplit';
            app.UIAxes2 = uiaxes(app.CoSplitTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.MethodDropDownLabel = uilabel(app.CoSplitTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.CoSplitTab);
            app.MethodDropDown.Items = {'Maximum Pool Size', 'Methode 2'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Maximum Pool Size';
            app.NumberEditFieldLabel = uilabel(app.CoSplitTab);
            app.NumberEditFieldLabel.Text = 'Number';
            app.NumberEditField = uieditfield(app.CoSplitTab, 'numeric');
            app.Label = uilabel(app.CoSplitTab);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.ConcentrationBEditFieldLabel = uilabel(app.UIFigure);
            app.ConcentrationBEditFieldLabel.Text = 'Concentration B';
            app.ConcentrationBEditField = uieditfield(app.UIFigure, 'numeric');
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interaction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end